classdef cancerNet < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        CancerMortalityRatePredictorLabel  matlab.ui.control.Label
        OnlyPublicInsuranceCoveragePercentageEditField  matlab.ui.control.NumericEditField
        OnlyPublicInsuranceCoveragePercentageEditFieldLabel  matlab.ui.control.Label
        IncidenceRateEditField         matlab.ui.control.NumericEditField
        IncidenceRateEditField_2Label  matlab.ui.control.Label
        PublicInsuranceCoveragePercentageEditField  matlab.ui.control.NumericEditField
        PublicInsuranceCoveragePercentageEditFieldLabel  matlab.ui.control.Label
        EmployerPrivateInsuranceCoveragePercentageEditField  matlab.ui.control.NumericEditField
        EmployerPrivateInsuranceCoveragePercentageEditField_2Label  matlab.ui.control.Label
        PrivateInsuranceCoveragePercentageEditField  matlab.ui.control.NumericEditField
        PrivateInsuranceCoveragePercentageEditFieldLabel  matlab.ui.control.Label
        MarriedPercentageEditField     matlab.ui.control.NumericEditField
        MarriedPercentageEditFieldLabel  matlab.ui.control.Label
        UnemploymentPercentage16EditField  matlab.ui.control.NumericEditField
        UnemploymentPercentage16EditFieldLabel  matlab.ui.control.Label
        PovertyPercentageEditField     matlab.ui.control.NumericEditField
        PovertyPercentageEditFieldLabel  matlab.ui.control.Label
        MedianIncomeEditField          matlab.ui.control.NumericEditField
        MedianIncomeEditFieldLabel     matlab.ui.control.Label
        AverageHouseholdSizeEditField  matlab.ui.control.NumericEditField
        MarriedHouseholdPercentageEditField  matlab.ui.control.NumericEditField
        AverageHouseholdSizeEditFieldLabel  matlab.ui.control.Label
        MarriedHouseholdPercentageEditFieldLabel  matlab.ui.control.Label
        OtherRacePercentageEditField   matlab.ui.control.NumericEditField
        OtherRacePercentageEditFieldLabel  matlab.ui.control.Label
        StudentsPerCapitaEditField     matlab.ui.control.NumericEditField
        StudentsPerCapitaEditFieldLabel  matlab.ui.control.Label
        HighSchoolGraduationPercentage25EditField  matlab.ui.control.NumericEditField
        HighSchoolGraduationPercentage25EditFieldLabel  matlab.ui.control.Label
        BachelorDegreePercentage1824EditField  matlab.ui.control.NumericEditField
        BachelorDegreePercentage1824EditFieldLabel  matlab.ui.control.Label
        HighSchoolGraduationPercentage1824EditField  matlab.ui.control.NumericEditField
        HighSchoolGraduationPercentage1824EditFieldLabel  matlab.ui.control.Label
        NonHighSchoolGraduationPercentage1824EditField  matlab.ui.control.NumericEditField
        NonHighSchoolGraduationPercentage1824EditFieldLabel  matlab.ui.control.Label
        AsianPercentageEditField       matlab.ui.control.NumericEditField
        AsianPercentageEditField_2Label  matlab.ui.control.Label
        AfricanAmericanPercentageEditField  matlab.ui.control.NumericEditField
        AfricanAmericanPercentageEditFieldLabel  matlab.ui.control.Label
        CaucasianPercentageEditField   matlab.ui.control.NumericEditField
        CaucasianPercentageEditFieldLabel  matlab.ui.control.Label
        BirthRateEditField             matlab.ui.control.NumericEditField
        BirthRateEditFieldLabel        matlab.ui.control.Label
        MedianAgeEditField             matlab.ui.control.NumericEditField
        MedianAgeEditFieldLabel        matlab.ui.control.Label
        PopulationEditField            matlab.ui.control.NumericEditField
        PopulationEditField_2Label     matlab.ui.control.Label
        MortalityRateEditField         matlab.ui.control.NumericEditField
        MortalityRateEditFieldLabel    matlab.ui.control.Label
        OUTPUTLabel                    matlab.ui.control.Label
        INPUTSLabel                    matlab.ui.control.Label
        CancerDetailLabel              matlab.ui.control.Label
        HouseholdDetailsLabel          matlab.ui.control.Label
        EducationDetailsLabel          matlab.ui.control.Label
        EconomicDetailsLabel           matlab.ui.control.Label
        EthnicityDetailsLabel          matlab.ui.control.Label
        InsuranceDetailsLabel          matlab.ui.control.Label
        DemographicOverviewLabel       matlab.ui.control.Label
        GenerateOutputButton           matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateOutputButton
        function GenerateOutputButtonPushed(app, event)
            load optimalNet.mat net
            
            input = [app.IncidenceRateEditField.Value; app.MedianIncomeEditField.Value; ...
                app.PopulationEditField.Value; app.PovertyPercentageEditField.Value; ...
                app.StudentsPerCapitaEditField.Value; app.MedianAgeEditField.Value; ...
                app.AverageHouseholdSizeEditField.Value; app.MarriedPercentageEditField.Value; ...
                app.NonHighSchoolGraduationPercentage1824EditField.Value; app.HighSchoolGraduationPercentage1824EditField.Value; ...
                app.BachelorDegreePercentage1824EditField.Value; app.HighSchoolGraduationPercentage25EditField.Value; ...
                app.UnemploymentPercentage16EditField.Value; app.PrivateInsuranceCoveragePercentageEditField.Value; ...
                app.EmployerPrivateInsuranceCoveragePercentageEditField.Value; app.PublicInsuranceCoveragePercentageEditField.Value; ...
                app.OnlyPublicInsuranceCoveragePercentageEditField.Value; app.CaucasianPercentageEditField.Value; ...
                app.AfricanAmericanPercentageEditField.Value; app.AsianPercentageEditField.Value; ...
                app.OtherRacePercentageEditField.Value; app.MarriedHouseholdPercentageEditField.Value; ...
                app.BirthRateEditField.Value];
            
            output = net(input);
            
            app.MortalityRateEditField.Value = output;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GenerateOutputButton = uibutton(app.UIFigure, 'push');
            app.GenerateOutputButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateOutputButtonPushed, true);
            app.GenerateOutputButton.Text = 'Generate Output';
            app.DemographicOverviewLabel = uilabel(app.UIFigure);
            app.DemographicOverviewLabel.Text = 'Demographic Overview';
            app.InsuranceDetailsLabel = uilabel(app.UIFigure);
            app.InsuranceDetailsLabel.Text = 'Insurance Details';
            app.EthnicityDetailsLabel = uilabel(app.UIFigure);
            app.EthnicityDetailsLabel.Text = 'Ethnicity Details';
            app.EconomicDetailsLabel = uilabel(app.UIFigure);
            app.EconomicDetailsLabel.Text = 'Economic Details';
            app.EducationDetailsLabel = uilabel(app.UIFigure);
            app.EducationDetailsLabel.Text = 'Education Details';
            app.HouseholdDetailsLabel = uilabel(app.UIFigure);
            app.HouseholdDetailsLabel.Text = 'Household Details';
            app.CancerDetailLabel = uilabel(app.UIFigure);
            app.CancerDetailLabel.Text = 'Cancer Detail';
            app.INPUTSLabel = uilabel(app.UIFigure);
            app.INPUTSLabel.Text = 'INPUTS';
            app.OUTPUTLabel = uilabel(app.UIFigure);
            app.OUTPUTLabel.Text = 'OUTPUT';
            app.MortalityRateEditFieldLabel = uilabel(app.UIFigure);
            app.MortalityRateEditFieldLabel.Text = 'Mortality Rate';
            app.MortalityRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.MortalityRateEditField.Editable = 'off';
            app.PopulationEditField_2Label = uilabel(app.UIFigure);
            app.PopulationEditField_2Label.Text = '2015 Population';
            app.PopulationEditField = uieditfield(app.UIFigure, 'numeric');
            app.PopulationEditField.Limits = [0 Inf];
            app.MedianAgeEditFieldLabel = uilabel(app.UIFigure);
            app.MedianAgeEditFieldLabel.Text = 'Median Age';
            app.MedianAgeEditField = uieditfield(app.UIFigure, 'numeric');
            app.MedianAgeEditField.Limits = [0 Inf];
            app.BirthRateEditFieldLabel = uilabel(app.UIFigure);
            app.BirthRateEditFieldLabel.Text = 'Birth Rate';
            app.BirthRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.BirthRateEditField.Limits = [0 100];
            app.CaucasianPercentageEditFieldLabel = uilabel(app.UIFigure);
            app.CaucasianPercentageEditFieldLabel.Text = 'Caucasian Percentage';
            app.CaucasianPercentageEditField = uieditfield(app.UIFigure, 'numeric');
            app.CaucasianPercentageEditField.Limits = [0 100];
            app.AfricanAmericanPercentageEditFieldLabel = uilabel(app.UIFigure);
            app.AfricanAmericanPercentageEditFieldLabel.Text = 'African American Percentage';
            app.AfricanAmericanPercentageEditField = uieditfield(app.UIFigure, 'numeric');
            app.AfricanAmericanPercentageEditField.Limits = [0 100];
            app.AsianPercentageEditField_2Label = uilabel(app.UIFigure);
            app.AsianPercentageEditField_2Label.Text = 'Asian Percentage';
            app.AsianPercentageEditField = uieditfield(app.UIFigure, 'numeric');
            app.AsianPercentageEditField.Limits = [0 100];
            app.NonHighSchoolGraduationPercentage1824EditFieldLabel = uilabel(app.UIFigure);
            app.NonHighSchoolGraduationPercentage1824EditFieldLabel.Text = 'Non-High School Graduation Percentage (18-24)';
            app.NonHighSchoolGraduationPercentage1824EditField = uieditfield(app.UIFigure, 'numeric');
            app.NonHighSchoolGraduationPercentage1824EditField.Limits = [0 100];
            app.HighSchoolGraduationPercentage1824EditFieldLabel = uilabel(app.UIFigure);
            app.HighSchoolGraduationPercentage1824EditFieldLabel.Text = 'High School Graduation Percentage (18-24)';
            app.HighSchoolGraduationPercentage1824EditField = uieditfield(app.UIFigure, 'numeric');
            app.HighSchoolGraduationPercentage1824EditField.Limits = [0 100];
            app.BachelorDegreePercentage1824EditFieldLabel = uilabel(app.UIFigure);
            app.BachelorDegreePercentage1824EditFieldLabel.Text = 'Bachelor Degree Percentage (18-24)';
            app.BachelorDegreePercentage1824EditField = uieditfield(app.UIFigure, 'numeric');
            app.BachelorDegreePercentage1824EditField.Limits = [0 100];
            app.HighSchoolGraduationPercentage25EditFieldLabel = uilabel(app.UIFigure);
            app.HighSchoolGraduationPercentage25EditFieldLabel.Text = 'High School Graduation Percentage (25+)';
            app.HighSchoolGraduationPercentage25EditField = uieditfield(app.UIFigure, 'numeric');
            app.HighSchoolGraduationPercentage25EditField.Limits = [0 100];
            app.StudentsPerCapitaEditFieldLabel = uilabel(app.UIFigure);
            app.StudentsPerCapitaEditFieldLabel.Text = 'Students Per Capita';
            app.StudentsPerCapitaEditField = uieditfield(app.UIFigure, 'numeric');
            app.StudentsPerCapitaEditField.Limits = [0 100];
            app.OtherRacePercentageEditFieldLabel = uilabel(app.UIFigure);
            app.OtherRacePercentageEditFieldLabel.Text = 'Other Race Percentage';
            app.OtherRacePercentageEditField = uieditfield(app.UIFigure, 'numeric');
            app.OtherRacePercentageEditField.Limits = [0 100];
            app.MarriedHouseholdPercentageEditFieldLabel = uilabel(app.UIFigure);
            app.MarriedHouseholdPercentageEditFieldLabel.Text = 'Married Household Percentage';
            app.AverageHouseholdSizeEditFieldLabel = uilabel(app.UIFigure);
            app.AverageHouseholdSizeEditFieldLabel.Text = 'Average Household Size';
            app.MarriedHouseholdPercentageEditField = uieditfield(app.UIFigure, 'numeric');
            app.MarriedHouseholdPercentageEditField.Limits = [0 100];
            app.AverageHouseholdSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.AverageHouseholdSizeEditField.Limits = [0 100];
            app.MedianIncomeEditFieldLabel = uilabel(app.UIFigure);
            app.MedianIncomeEditFieldLabel.Text = 'Median Income';
            app.MedianIncomeEditField = uieditfield(app.UIFigure, 'numeric');
            app.MedianIncomeEditField.Limits = [0 100];
            app.PovertyPercentageEditFieldLabel = uilabel(app.UIFigure);
            app.PovertyPercentageEditFieldLabel.Text = 'Poverty Percentage';
            app.PovertyPercentageEditField = uieditfield(app.UIFigure, 'numeric');
            app.PovertyPercentageEditField.Limits = [0 100];
            app.UnemploymentPercentage16EditFieldLabel = uilabel(app.UIFigure);
            app.UnemploymentPercentage16EditFieldLabel.Text = 'Unemployment Percentage (16+)';
            app.UnemploymentPercentage16EditField = uieditfield(app.UIFigure, 'numeric');
            app.UnemploymentPercentage16EditField.Limits = [0 100];
            app.MarriedPercentageEditFieldLabel = uilabel(app.UIFigure);
            app.MarriedPercentageEditFieldLabel.Text = 'Married Percentage';
            app.MarriedPercentageEditField = uieditfield(app.UIFigure, 'numeric');
            app.MarriedPercentageEditField.Limits = [0 100];
            app.PrivateInsuranceCoveragePercentageEditFieldLabel = uilabel(app.UIFigure);
            app.PrivateInsuranceCoveragePercentageEditFieldLabel.Text = 'Private Insurance Coverage Percentage';
            app.PrivateInsuranceCoveragePercentageEditField = uieditfield(app.UIFigure, 'numeric');
            app.PrivateInsuranceCoveragePercentageEditField.Limits = [0 100];
            app.EmployerPrivateInsuranceCoveragePercentageEditField_2Label = uilabel(app.UIFigure);
            app.EmployerPrivateInsuranceCoveragePercentageEditField_2Label.Text = 'Employer Private Insurance Coverage Percentage';
            app.EmployerPrivateInsuranceCoveragePercentageEditField = uieditfield(app.UIFigure, 'numeric');
            app.EmployerPrivateInsuranceCoveragePercentageEditField.Limits = [0 100];
            app.PublicInsuranceCoveragePercentageEditFieldLabel = uilabel(app.UIFigure);
            app.PublicInsuranceCoveragePercentageEditFieldLabel.Text = 'Public Insurance Coverage Percentage';
            app.PublicInsuranceCoveragePercentageEditField = uieditfield(app.UIFigure, 'numeric');
            app.PublicInsuranceCoveragePercentageEditField.Limits = [0 100];
            app.IncidenceRateEditField_2Label = uilabel(app.UIFigure);
            app.IncidenceRateEditField_2Label.Text = 'Incidence Rate';
            app.IncidenceRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.IncidenceRateEditField.Limits = [0 100];
            app.OnlyPublicInsuranceCoveragePercentageEditFieldLabel = uilabel(app.UIFigure);
            app.OnlyPublicInsuranceCoveragePercentageEditFieldLabel.Text = 'Only Public Insurance Coverage Percentage';
            app.OnlyPublicInsuranceCoveragePercentageEditField = uieditfield(app.UIFigure, 'numeric');
            app.OnlyPublicInsuranceCoveragePercentageEditField.Limits = [0 100];
            app.CancerMortalityRatePredictorLabel = uilabel(app.UIFigure);
            app.CancerMortalityRatePredictorLabel.Text = 'Cancer Mortality Rate Predictor';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cancerNet

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end