classdef optimizeApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ActivationFunction3EditField    matlab.ui.control.EditField
        ActivationFunction3EditFieldLabel  matlab.ui.control.Label
        ActivationFunction2EditField    matlab.ui.control.EditField
        ActivationFunction2EditFieldLabel  matlab.ui.control.Label
        ActivationFunction1EditField    matlab.ui.control.EditField
        ActivationFunction1Label        matlab.ui.control.Label
        HiddenLayer3SizeEditField       matlab.ui.control.NumericEditField
        HiddenLayer3SizeEditFieldLabel  matlab.ui.control.Label
        HiddenLayer2SizeEditField       matlab.ui.control.NumericEditField
        HiddenLayer2SizeEditFieldLabel  matlab.ui.control.Label
        HiddenLayer1SizeEditField       matlab.ui.control.NumericEditField
        HiddenLayer1SizeEditFieldLabel  matlab.ui.control.Label
        NumberofHiddenLayersEditField   matlab.ui.control.NumericEditField
        NumberofHiddenLayersEditFieldLabel  matlab.ui.control.Label
        SwarmSizeEditField              matlab.ui.control.NumericEditField
        SwarmSizeEditFieldLabel         matlab.ui.control.Label
        MaximumIterationsEditField      matlab.ui.control.NumericEditField
        MaximumIterationsEditFieldLabel  matlab.ui.control.Label
        MaximumTimesEditField           matlab.ui.control.NumericEditField
        MaximumTimesEditFieldLabel      matlab.ui.control.Label
        OptimizationOptionsLabel        matlab.ui.control.Label
        GENERATEOUTPUTButton            matlab.ui.control.Button
        MSEEditField                    matlab.ui.control.NumericEditField
        MSEEditFieldLabel               matlab.ui.control.Label
        OPTIMIZATIONOUTPUTSLabel        matlab.ui.control.Label
        MaximumSizeEditField_2          matlab.ui.control.NumericEditField
        MaximumSizeEditField_2Label     matlab.ui.control.Label
        MInimumSizeEditField_2          matlab.ui.control.NumericEditField
        MInimumSizeEditField_2Label     matlab.ui.control.Label
        HiddenLayerSizeLabel_2          matlab.ui.control.Label
        MaximumSizeEditField            matlab.ui.control.NumericEditField
        MaximumSizeEditFieldLabel       matlab.ui.control.Label
        MInimumSizeEditField            matlab.ui.control.NumericEditField
        MInimumSizeEditFieldLabel       matlab.ui.control.Label
        HiddenLayerSizeLabel            matlab.ui.control.Label
        NumberofHiddenLayersLabel       matlab.ui.control.Label
        MaximumHiddenLayersEditField    matlab.ui.control.NumericEditField
        MaximumHiddenLayersEditFieldLabel  matlab.ui.control.Label
        MinimumHiddenLayersEditField    matlab.ui.control.NumericEditField
        MinimumHiddenLayersEditFieldLabel  matlab.ui.control.Label
        HYPERPARAMETERINPUTSLabel       matlab.ui.control.Label
        ParticleSwarmOptimizerforNeuralNetworkHyperparametersLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GENERATEOUTPUTButton
        function GENERATEOUTPUTButtonPushed(app, event)
            cancerData = readtable("cancer_reg.csv");

            % output
            Targets = cancerData.TARGET_deathRate';
            
            % inputs
            Inputs = cancerData;
            
            % remove columns with missing data and target
            Inputs = removevars(Inputs,{'TARGET_deathRate', 'MedianAgeMale', 'MedianAgeFemale', ...
                                        'binnedInc', 'avgAnnCount',  'avgDeathsPerYear', ...
                                        'Geography', 'PctSomeCol18_24', 'PctBachDeg25_Over', ...
                                        'PctEmployed16_Over', 'PctPrivateCoverageAlone'});
            Inputs = table2array(Inputs)';  
            
            lb = [app.MinimumHiddenLayersEditField.Value, app.MInimumSizeEditField.Value, app.MInimumSizeEditField.Value, app.MInimumSizeEditField.Value, 1, 1, 1];
            ub = [app.MaximumHiddenLayersEditField.Value, app.MaximumSizeEditField.Value, app.MaximumSizeEditField.Value, app.MaximumSizeEditField.Value, 15, 15, 15];
            
            options = optimoptions(@particleswarm, 'MaxTime', app.MaximumTimesEditField.Value, 'SwarmSize',app.SwarmSizeEditField.Value, 'PlotFcn', 'pswplotbestf','MaxIterations',app.MaximumIterationsEditField.Value);
            actFunc = {'logsig', 'tansig', 'satlins','purelin', 'poslin', 'satlin', 'compet', 'elliotsig', 'hardlim', 'hardlims', 'netinv', 'radbas', 'radbasn', 'softmax', 'tribas'}; %ub 15

            ObjFcn = makeObjFcn(Inputs,Targets);
            [X, fbest, exitflag,out] = particleswarm(ObjFcn, 7, lb, ub, options);
            app.MSEEditField.Value = fbest;
            app.NumberofHiddenLayersEditField.Value = round(X(1));
            app.HiddenLayer1SizeEditField.Value = round(X(2));
            app.HiddenLayer2SizeEditField.Value = round(X(3));
            app.HiddenLayer3SizeEditField.Value = round(X(4));
            app.ActivationFunction1EditField.Value = char(actFunc(round(X(5))));
            app.ActivationFunction2EditField.Value = char(actFunc(round(X(6))));
            app.ActivationFunction3EditField.Value = char(actFunc(round(X(7))));
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ParticleSwarmOptimizerforNeuralNetworkHyperparametersLabel = uilabel(app.UIFigure);
            app.ParticleSwarmOptimizerforNeuralNetworkHyperparametersLabel.Text = 'Particle Swarm Optimizer for Neural Network Hyperparameters';
            app.HYPERPARAMETERINPUTSLabel = uilabel(app.UIFigure);
            app.HYPERPARAMETERINPUTSLabel.Text = 'HYPERPARAMETER INPUTS';
            app.MinimumHiddenLayersEditFieldLabel = uilabel(app.UIFigure);
            app.MinimumHiddenLayersEditFieldLabel.Text = 'Minimum Hidden Layers';
            app.MinimumHiddenLayersEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinimumHiddenLayersEditField.Limits = [1 3];
            app.MinimumHiddenLayersEditField.RoundFractionalValues = 'on';
            app.MinimumHiddenLayersEditField.Value = 1;
            app.MaximumHiddenLayersEditFieldLabel = uilabel(app.UIFigure);
            app.MaximumHiddenLayersEditFieldLabel.Text = 'Maximum Hidden Layers';
            app.MaximumHiddenLayersEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaximumHiddenLayersEditField.Limits = [1 3];
            app.MaximumHiddenLayersEditField.RoundFractionalValues = 'on';
            app.MaximumHiddenLayersEditField.Value = 1;
            app.NumberofHiddenLayersLabel = uilabel(app.UIFigure);
            app.NumberofHiddenLayersLabel.Text = 'Number of Hidden Layers';
            app.HiddenLayerSizeLabel = uilabel(app.UIFigure);
            app.HiddenLayerSizeLabel.Text = 'Hidden Layer Size';
            app.MInimumSizeEditFieldLabel = uilabel(app.UIFigure);
            app.MInimumSizeEditFieldLabel.Text = 'MInimum Size';
            app.MInimumSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.MInimumSizeEditField.Limits = [1 Inf];
            app.MInimumSizeEditField.RoundFractionalValues = 'on';
            app.MInimumSizeEditField.Value = 1;
            app.MaximumSizeEditFieldLabel = uilabel(app.UIFigure);
            app.MaximumSizeEditFieldLabel.Text = 'Maximum Size';
            app.MaximumSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaximumSizeEditField.Limits = [1 Inf];
            app.MaximumSizeEditField.RoundFractionalValues = 'on';
            app.MaximumSizeEditField.Value = 1;
            app.HiddenLayerSizeLabel_2 = uilabel(app.UIFigure);
            app.HiddenLayerSizeLabel_2.Text = 'Hidden Layer Size';
            app.MInimumSizeEditField_2Label = uilabel(app.UIFigure);
            app.MInimumSizeEditField_2Label.Text = 'MInimum Size';
            app.MInimumSizeEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.MInimumSizeEditField_2.Limits = [1 Inf];
            app.MInimumSizeEditField_2.RoundFractionalValues = 'on';
            app.MInimumSizeEditField_2.Value = 1;
            app.MaximumSizeEditField_2Label = uilabel(app.UIFigure);
            app.MaximumSizeEditField_2Label.Text = 'Maximum Size';
            app.MaximumSizeEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.MaximumSizeEditField_2.Limits = [1 Inf];
            app.MaximumSizeEditField_2.RoundFractionalValues = 'on';
            app.MaximumSizeEditField_2.Value = 1;
            app.OPTIMIZATIONOUTPUTSLabel = uilabel(app.UIFigure);
            app.OPTIMIZATIONOUTPUTSLabel.Text = 'OPTIMIZATION OUTPUTS';
            app.MSEEditFieldLabel = uilabel(app.UIFigure);
            app.MSEEditFieldLabel.Text = 'MSE';
            app.MSEEditField = uieditfield(app.UIFigure, 'numeric');
            app.GENERATEOUTPUTButton = uibutton(app.UIFigure, 'push');
            app.GENERATEOUTPUTButton.ButtonPushedFcn = createCallbackFcn(app, @GENERATEOUTPUTButtonPushed, true);
            app.GENERATEOUTPUTButton.Text = 'GENERATE OUTPUT';
            app.OptimizationOptionsLabel = uilabel(app.UIFigure);
            app.OptimizationOptionsLabel.Text = 'Optimization Options';
            app.MaximumTimesEditFieldLabel = uilabel(app.UIFigure);
            app.MaximumTimesEditFieldLabel.Text = 'Maximum Time (s)';
            app.MaximumTimesEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaximumTimesEditField.Limits = [0 Inf];
            app.MaximumTimesEditField.RoundFractionalValues = 'on';
            app.MaximumIterationsEditFieldLabel = uilabel(app.UIFigure);
            app.MaximumIterationsEditFieldLabel.Text = 'Maximum Iterations';
            app.MaximumIterationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaximumIterationsEditField.Limits = [1 Inf];
            app.MaximumIterationsEditField.RoundFractionalValues = 'on';
            app.MaximumIterationsEditField.Value = 1;
            app.SwarmSizeEditFieldLabel = uilabel(app.UIFigure);
            app.SwarmSizeEditFieldLabel.Text = 'Swarm Size';
            app.SwarmSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.SwarmSizeEditField.Limits = [2 Inf];
            app.SwarmSizeEditField.RoundFractionalValues = 'on';
            app.SwarmSizeEditField.Value = 2;
            app.NumberofHiddenLayersEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofHiddenLayersEditFieldLabel.Text = 'Number of Hidden Layers';
            app.NumberofHiddenLayersEditField = uieditfield(app.UIFigure, 'numeric');
            app.HiddenLayer1SizeEditFieldLabel = uilabel(app.UIFigure);
            app.HiddenLayer1SizeEditFieldLabel.Text = 'Hidden Layer 1 Size';
            app.HiddenLayer1SizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.HiddenLayer2SizeEditFieldLabel = uilabel(app.UIFigure);
            app.HiddenLayer2SizeEditFieldLabel.Text = 'Hidden Layer 2 Size';
            app.HiddenLayer2SizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.HiddenLayer3SizeEditFieldLabel = uilabel(app.UIFigure);
            app.HiddenLayer3SizeEditFieldLabel.Text = 'Hidden Layer 3 Size';
            app.HiddenLayer3SizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.ActivationFunction1Label = uilabel(app.UIFigure);
            app.ActivationFunction1Label.Text = 'Activation Function 1';
            app.ActivationFunction1EditField = uieditfield(app.UIFigure, 'text');
            app.ActivationFunction2EditFieldLabel = uilabel(app.UIFigure);
            app.ActivationFunction2EditFieldLabel.Text = 'Activation Function 2';
            app.ActivationFunction2EditField = uieditfield(app.UIFigure, 'text');
            app.ActivationFunction3EditFieldLabel = uilabel(app.UIFigure);
            app.ActivationFunction3EditFieldLabel.Text = 'Activation Function 3';
            app.ActivationFunction3EditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = optimizeApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end